classdef T1optimizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        progressGauge            matlab.ui.control.LinearGauge
        progressGaugeLabel       matlab.ui.control.Label
        MRILoadButton            matlab.ui.control.Button
        T1FileLocationLabel      matlab.ui.control.Label
        SelectfileButton         matlab.ui.control.Button
        SegmentationButton       matlab.ui.control.Button
        MakeameshButton          matlab.ui.control.Button
        PairedcomputationButton  matlab.ui.control.Button
        TIoptimizationButton     matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SelectedNiiFile
        SelectedNiiFileName
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)

        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            % select sensor coordinates file
            fileName = uigetfile('*.nii','Select a NIfTI file');
            if fileName ~= 0 % 사용자가 파일을 선택한 경우
                app.SelectedNiiFile = niftiread(fileName);
            end
            app.T1FileLocationLabel.Text = fileName;
            app.SelectedNiiFileName=fileName;

        end

        % Button pushed function: MRILoadButton
        function MRILoadButtonPushed(app, event)
            if isempty(app.SelectedNiiFile)
                errordlg('Please select a NIfTI file first.', 'Error');
                return;
            end
            load_untouch_nii(app.SelectedNiiFileName); % call load_untouch_nii func
            
            % UIAxes 컴포넌트의 Handle을 얻어옵니다.
            ax = app.UIAxes; % app은 MATLAB App Designer에서 생성된 App의 이름입니다.
            
            % NIfTI 3D 볼륨 데이터를 montage 함수로 표시합니다.
            h = montage(app.SelectedNiiFile, 'Parent', ax);
            
            % 가운데 슬라이스의 인덱스 계산
            middleSliceIdx = floor(size(app.SelectedNiiFile, 3) / 2);
            
            % 가운데 슬라이스 선택 및 표시
            middleSlice = app.SelectedNiiFile(:, :, middleSliceIdx);
            imshow(middleSlice, [], 'Parent', ax);
            
            % 타이틀을 설정합니다.
            title(ax, 'Middle Slice');
            disp("T1 Loaded.")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TIoptimizationButton = uibutton(app.UIFigure, 'push');
            app.TIoptimizationButton.Text = 'TI optimization';
            app.PairedcomputationButton = uibutton(app.UIFigure, 'push');
            app.PairedcomputationButton.Text = 'Paired computation';
            app.MakeameshButton = uibutton(app.UIFigure, 'push');
            app.MakeameshButton.Text = 'Make a mesh';
            app.SegmentationButton = uibutton(app.UIFigure, 'push');
            app.SegmentationButton.Text = 'Segmentation';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.T1FileLocationLabel = uilabel(app.UIFigure);
            app.T1FileLocationLabel.Text = 'T1 File Location';
            app.MRILoadButton = uibutton(app.UIFigure, 'push');
            app.MRILoadButton.ButtonPushedFcn = createCallbackFcn(app, @MRILoadButtonPushed, true);
            app.MRILoadButton.Text = 'MRI Load';
            app.progressGaugeLabel = uilabel(app.UIFigure);
            app.progressGaugeLabel.Text = 'progress';
            app.progressGauge = uigauge(app.UIFigure, 'linear');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1optimizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end